classdef APPv2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        scaInput                matlab.ui.control.Spinner
        SpinnerLabel            matlab.ui.control.Label
        Slider                  matlab.ui.control.Slider
        SliderLabel             matlab.ui.control.Label
        CircumferenceEditField  matlab.ui.control.NumericEditField
        CircumferenceLabel      matlab.ui.control.Label
        LoadSTLButton           matlab.ui.control.Button
        scaPlot                 matlab.ui.control.UIAxes
        orPlot                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        TR % Description
        % Property added to store the link object for synced 3D rotation
        LinkObject
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadSTLButton
        function LoadSTLButtonPushed(app, event)
            % --- STL Import and Visualization ---
            [file, path] = uigetfile('*.stl', 'Select STL file');
            if isequal(file, 0)
                return;
            end
            fascicle_file = fullfile(path, file);
            app.TR = stlread(fascicle_file);
            
            % Extract P
            P = app.TR.Points;
            
            % Clear the axes before plotting new data
            cla(app.orPlot);
            cla(app.scaPlot);
            
            % Plot the STL geometry
            patch(app.orPlot, ...
                'Faces', app.TR.ConnectivityList, ...
                'Vertices', P, ... 
                'FaceColor', [1 0.1 0.1], ...
                'EdgeColor', 'none', ...
                'FaceAlpha', 0.5);
                
            %%% === NEW/CHANGED === %%%
            % 1. DataAspectRatio = [1 1 1] prevents distortion (replaces axis equal)
            % 2. PlotBoxAspectRatio = [1 1 1] forces the physical UI box to be a rigid cube
            % This stops the window from squashing when the object gets wide.
            app.orPlot.DataAspectRatio = [1 1 1];
            app.orPlot.PlotBoxAspectRatio = [1 1 1];
            
            app.scaPlot.DataAspectRatio = [1 1 1];
            app.scaPlot.PlotBoxAspectRatio = [1 1 1];
            
            camlight(app.orPlot);
            lighting(app.orPlot, 'gouraud');
            
            % Link ONLY the cameras (View) so rotating one rotates the other
            app.LinkObject = linkprop([app.orPlot, app.scaPlot], {'View'});
            %%% =================== %%%
            
            % --- Circumference Calculation ---
            % Define slice boundaries (middle 20% of Z)
            Zmin = 1.395; 
            Zmax = 1.4; 
            
            % Extract slice points within the Z-range
            sliceIdx = (P(:, 3) >= Zmin) & (P(:, 3) <= Zmax);
            slicePts = P(sliceIdx, 1:2);
            
            % Check if enough points exist to form a polygon
            if size(slicePts, 1) < 3
                app.CircumferenceEditField.Value = 0;
                return;
            end
            
            % Calculate Convex Hull
            K = convhull(slicePts(:, 1), slicePts(:, 2));
            hullPts = slicePts(K, :);
            
            % Calculate Circumference (sum of Euclidean distances between points)
            d = diff(hullPts, 1, 1);
            currentCirc = sum(sqrt(sum(d.^2, 2)));
            
            % Display result in the UI
            app.CircumferenceEditField.Value = currentCirc;
            
            % --- Overlay Slice Visualization ---
            zLevel = mean([Zmin, Zmax]);
            hold(app.orPlot, 'on');
            
            % Plot the blue hull outline at the slice level
            plot3(app.orPlot, hullPts(:, 1), hullPts(:, 2), zLevel * ones(size(hullPts, 1), 1), ...
                'b', 'LineWidth', 2);
            title(app.orPlot, file, 'Interpreter', 'none');
            hold(app.orPlot, 'off');
        end

        % Value changed function: scaInput
        function scaInputValueChanged(app, event)
            % Check if STL is loaded
            if isempty(app.TR)
                return;
            end
        
            % Get scale factor from spinner
            scaleFactor = app.scaInput.Value;
        
            % Original points
            P = app.TR.Points;
            F = app.TR.ConnectivityList;
        
            % Compute center
            centerXY = mean(P(:,1:2), 1);
        
            % Apply scaling (XY only)
            P_new = P;
            P_new(:,1) = centerXY(1) + scaleFactor * (P(:,1) - centerXY(1));
            P_new(:,2) = centerXY(2) + scaleFactor * (P(:,2) - centerXY(2));
        
            % Clear the scaled plot
            cla(app.scaPlot);
            
            %%% === NEW/CHANGED === %%%
            % 1. Plot the ORIGINAL STL as a ghosted red reference
            patch(app.scaPlot, ...
                'Faces', F, ...
                'Vertices', P, ...
                'FaceColor', [1 0.1 0.1], ... % Same red as the first plot
                'EdgeColor', 'none', ...
                'FaceAlpha', 0.2);            % Very transparent ghost
            %%% =================== %%%
            
            % 2. Plot the SCALED STL over it
            patch(app.scaPlot, ...
                'Faces', F, ...
                'Vertices', P_new, ...
                'FaceColor', [0.1 0.3 1], ... % Blue
                'EdgeColor', 'none', ...
                'FaceAlpha', 0.6);            % Slightly more solid
            
            % Re-enforce the rigid rules after redrawing
            app.scaPlot.DataAspectRatio = [1 1 1];
            app.scaPlot.PlotBoxAspectRatio = [1 1 1];
            
            camlight(app.scaPlot);
            lighting(app.scaPlot, 'gouraud');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.orPlot = uiaxes(app.UIFigure);
            title(app.orPlot, 'Title')
            xlabel(app.orPlot, 'X')
            ylabel(app.orPlot, 'Y')
            zlabel(app.orPlot, 'Z')
            app.scaPlot = uiaxes(app.UIFigure);
            title(app.scaPlot, 'Title')
            xlabel(app.scaPlot, 'X')
            ylabel(app.scaPlot, 'Y')
            zlabel(app.scaPlot, 'Z')
            app.LoadSTLButton = uibutton(app.UIFigure, 'push');
            app.LoadSTLButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSTLButtonPushed, true);
            app.LoadSTLButton.Text = 'Load STL';
            app.CircumferenceLabel = uilabel(app.UIFigure);
            app.CircumferenceLabel.Text = 'Circumference';
            app.CircumferenceEditField = uieditfield(app.UIFigure, 'numeric');
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.SpinnerLabel = uilabel(app.UIFigure);
            app.SpinnerLabel.Text = 'Spinner';
            app.scaInput = uispinner(app.UIFigure);
            app.scaInput.Step = 0.1;
            app.scaInput.Limits = [0.1 10];
            app.scaInput.ValueChangedFcn = createCallbackFcn(app, @scaInputValueChanged, true);
            app.scaInput.Value = 0.1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APPv2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end